classdef adohe_fototoko < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenMenu                  matlab.ui.container.Menu
        ToolsMenu                 matlab.ui.container.Menu
        HistogramMenu             matlab.ui.container.Menu
        BrigthnessMenu            matlab.ui.container.Menu
        NegativeMenu              matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        AboutFototokoMenu         matlab.ui.container.Menu
        HelpMenu_2                matlab.ui.container.Menu
        Toolbar                   matlab.ui.container.Toolbar
        PushTool                  matlab.ui.container.toolbar.PushTool
        PushTool2                 matlab.ui.container.toolbar.PushTool
        PushTool3                 matlab.ui.container.toolbar.PushTool
        GridLayout                matlab.ui.container.GridLayout
        TabGroup2                 matlab.ui.container.TabGroup
        HistogramTab_2            matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
        OutputLabel               matlab.ui.control.Label
        InputLabel                matlab.ui.control.Label
        UIAxes6                   matlab.ui.control.UIAxes
        UIAxes5                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        OutputImageTab            matlab.ui.container.Tab
        GridLayout5               matlab.ui.container.GridLayout
        InputImage                matlab.ui.control.Image
        OutputImage               matlab.ui.control.Image
        TabGroup                  matlab.ui.container.TabGroup
        HistogramTab              matlab.ui.container.Tab
        GridLayout6               matlab.ui.container.GridLayout
        ChooseFIleButton          matlab.ui.control.Button
        NoFileSelectedLabel       matlab.ui.control.Label
        BrightnessTab             matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        ApplyButton_2             matlab.ui.control.Button
        EnteradanbvaluesarbLabel  matlab.ui.control.Label
        BrightnessB               matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        ProcessButton             matlab.ui.control.Button
        BrightnessA               matlab.ui.control.NumericEditField
        aEditFieldLabel           matlab.ui.control.Label
        NegativeTab               matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        ApplyButton               matlab.ui.control.Button
        ProcessButton_2           matlab.ui.control.Button
        LogTransformationTab      matlab.ui.container.Tab
        GridLayout2_2             matlab.ui.container.GridLayout
        LogApply                  matlab.ui.control.Button
        Entercvaluesclog1rLabel   matlab.ui.control.Label
        LogProcess                matlab.ui.control.Button
        LogC                      matlab.ui.control.NumericEditField
        cLabel                    matlab.ui.control.Label
        PowerTransformationTab    matlab.ui.container.Tab
        GridLayout2_3             matlab.ui.container.GridLayout
        PowerGamma                matlab.ui.control.NumericEditField
        cLabel_3                  matlab.ui.control.Label
        LogApply_2                matlab.ui.control.Button
        EntercandgammavaluescrgammaLabel  matlab.ui.control.Label
        PowerProcess              matlab.ui.control.Button
        PowerC                    matlab.ui.control.NumericEditField
        cLabel_2                  matlab.ui.control.Label
        ContrastStretchingTab     matlab.ui.container.Tab
        GridLayout2_4             matlab.ui.container.GridLayout
        LogApply_3                matlab.ui.control.Button
        StretchingProcess         matlab.ui.control.Button
        EqualizationTab           matlab.ui.container.Tab
        GridLayout7               matlab.ui.container.GridLayout
        EqualizationProcess       matlab.ui.control.Button
        NoFileSelectedLabel_2     matlab.ui.control.Label
        ChooseFIleButton_2        matlab.ui.control.Button
    end

    
    properties (Access = private)
        Fullimagename % Full image name
        Inputimagedata % Image data (input)
        Outputimagedata % Image data (output)
    end
    
    methods (Access = private)
        
        function setHistogramInput(app, numChannel)
            
            % clear histogram
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            title(app.UIAxes, '');
            title(app.UIAxes2, '');
            title(app.UIAxes3, '');

            % handle rgb image
            if numChannel == 3
                % Display the image in the ui image component
                app.InputImage.ImageSource = app.Inputimagedata;
            
                % Separate the RGB channels
                redChannel = app.Inputimagedata(:, :, 1);
                greenChannel = app.Inputimagedata(:, :, 2);
                blueChannel = app.Inputimagedata(:, :, 3);
                
                % create histogram matrix
                redHistogram = zeros(256, 1);
                greenHistogram = zeros(256, 1);
                blueHistogram = zeros(256, 1);             

                % Calculate histograms for each channel
                [height, width] = size(redChannel);
                
                % calculate r,g, and b value
                for i = 1:height
                    for j = 1:width
                        redValue = redChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        greenValue = greenChannel(i, j) + 1;
                        blueValue = blueChannel(i, j) + 1;
                        
                        redHistogram(redValue) = redHistogram(redValue) + 1;
                        greenHistogram(greenValue) = greenHistogram(greenValue) + 1;
                        blueHistogram(blueValue) = blueHistogram(blueValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes, 0:255, redHistogram, 'r'); % Red channel histogram
                hold(app.UIAxes, 'on');
                bar(app.UIAxes2, 0:255, greenHistogram, 'g'); % Green channel histogram
                hold(app.UIAxes2, 'on');
                bar(app.UIAxes3, 0:255, blueHistogram, 'b'); % Blue channel histogram
                hold(app.UIAxes3, 'on');
                
                % Set titles for each axes
                title(app.UIAxes, 'Red Channel Histogram');
                title(app.UIAxes2, 'Green Channel Histogram');
                title(app.UIAxes3, 'Blue Channel Histogram');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes, [0, max(redHistogram)]); 
                ylim(app.UIAxes2, [0, max(greenHistogram)]);
                ylim(app.UIAxes3, [0, max(blueHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes, [0, 256]);
                xlim(app.UIAxes2, [0, 256]);
                xlim(app.UIAxes3, [0, 256]);

                % Turn off hold for all axes
                hold(app.UIAxes, 'off');
                hold(app.UIAxes2, 'off');
                hold(app.UIAxes3, 'off');
           
           % handle grayscale image
           elseif numChannel == 1
                % set to show image for greyscale
                rgbimage = cat(3, app.Inputimagedata(:, :, 1),app.Inputimagedata(:, :, 1),app.Inputimagedata(:, :, 1));
                % Display the image in the uiimage component
                app.InputImage.ImageSource = rgbimage;
                
                % grey channel
                greyChannel = app.Inputimagedata(:, :, 1);
                
                % grey histogram
                greyHistogram = zeros(256, 1);

                % Calculate histograms for each channel
                [height, width] = size(greyChannel);
                
                % calculate grey value
                for i = 1:height
                    for j = 1:width
                        greyValue = greyChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        
                        greyHistogram(greyValue) = greyHistogram(greyValue) + 1;
                    end
                end
                
                % plot histograms
                bar(app.UIAxes,0:255, greyHistogram, 'k'); % Grey channel histogram
                hold(app.UIAxes, 'on');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes, [0, max(greyHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes, [0, 256]);
                
                % Set a title for the axis
                title(app.UIAxes, 'Grayscale Image Histogram');
                hold(app.UIAxes, 'off');
           else
                % Handle other cases (e.g., images with more than 3 channels)
                msgbox('Unsupported image format', 'Error', 'error');
           end
        end
        
        function setHistogramOutput(app, numChannel)
            % clear histogram
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            title(app.UIAxes4, '');
            title(app.UIAxes5, '');
            title(app.UIAxes6, '');
            
            % handle rgb image
            if numChannel == 3
                % Display the image in the uiimage component
                app.OutputImage.ImageSource = app.Outputimagedata;
            
                % Separate the RGB channels
                redChannel = app.Outputimagedata(:, :, 1);
                greenChannel = app.Outputimagedata(:, :, 2);
                blueChannel = app.Outputimagedata(:, :, 3);
        
                % set value histogram
                redHistogram = zeros(256, 1);
                greenHistogram = zeros(256, 1);
                blueHistogram = zeros(256, 1);
                
                % calculate histograms for each channel
                [height, width] = size(redChannel);
                
                % calculate histogram
                for i = 1:height
                    for j = 1:width
                        redValue = redChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        greenValue = greenChannel(i, j) + 1;
                        blueValue = blueChannel(i, j) + 1;
                        
                        redHistogram(redValue) = redHistogram(redValue) + 1;
                        greenHistogram(greenValue) = greenHistogram(greenValue) + 1;
                        blueHistogram(blueValue) = blueHistogram(blueValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes4, 0:255, redHistogram, 'r'); % Red channel histogram
                hold(app.UIAxes4, 'on');
                bar(app.UIAxes5, 0:255, greenHistogram, 'g'); % Green channel histogram
                hold(app.UIAxes5, 'on');
                bar(app.UIAxes6, 0:255, blueHistogram, 'b'); % Blue channel histogram
                hold(app.UIAxes6, 'on');
    
                % Set titles for each axes
                title(app.UIAxes4, 'Red Channel Histogram');
                title(app.UIAxes5, 'Green Channel Histogram');
                title(app.UIAxes6, 'Blue Channel Histogram');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes4, [0, max(redHistogram)]); 
                ylim(app.UIAxes5, [0, max(greenHistogram)]);
                ylim(app.UIAxes6, [0, max(blueHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes4, [0, 256]);
                xlim(app.UIAxes5, [0, 256]);
                xlim(app.UIAxes6, [0, 256]);

                % Turn off hold for all axes
                hold(app.UIAxes4, 'off');
                hold(app.UIAxes5, 'off');
                hold(app.UIAxes6, 'off');

            % handle grayscale image
            elseif numChannel == 1
                % convert grayscale to format rgb to show image
                rgbimage = cat(3, app.Outputimagedata,app.Outputimagedata,app.Outputimagedata);

                % Display the image in the uiimage component
                app.OutputImage.ImageSource = rgbimage;
            
                % grey channel
                greyChannel = app.Outputimagedata(:, :, 1);
                
                % grey histogram
                greyHistogram = zeros(256, 1);

                % Calculate histograms for each channel
                [height, width] = size(greyChannel);
                
                % calculate histogram
                for i = 1:height
                    for j = 1:width
                        greyValue = greyChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        
                        greyHistogram(greyValue) = greyHistogram(greyValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes4, 0:255, greyHistogram, 'k'); % Grey channel histogram
                hold(app.UIAxes4, 'on');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes4, [0, max(greyHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes4, [0, 256]);

                % Set a title for the axis
                title(app.UIAxes4, 'Grayscale Image Histogram');
                hold(app.UIAxes4, 'off');
            end
        end
            
          
        function setText(app, label)
            % set text label
            app.NoFileSelectedLabel_2.Text = label;
            app.NoFileSelectedLabel.Text = label;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessButton
        function processBrightness(app, event)
            sz = size(app.Inputimagedata);
            for i = 1:sz(1)
                for j = 1:sz(2)
                    for k = 1:sz(3)
                        s = app.BrightnessA.Value * app.Inputimagedata(i,j,k) + app.BrightnessB.Value;
                        if s > 255
                            s = 255;
                        elseif s < 0
                            s = 0;
                        end
                        app.Outputimagedata(i,j,k) = s;
                    end
                end
            end
            
            setHistogramOutput(app, sz(3));
        end

        % Button pushed function: ProcessButton_2
        function processNegative(app, event)
            app.Outputimagedata = 255 - app.Inputimagedata;
            app.OutputImage.ImageSource = app.Outputimagedata;
        end

        % Button pushed function: ApplyButton, ApplyButton_2, LogApply
        function apply(app, event)
            app.Inputimagedata = app.Outputimagedata;
            app.InputImage.ImageSource = app.Inputimagedata;
        end

        % Callback function: ChooseFIleButton, ChooseFIleButton_2, OpenMenu
        function chooseFileHandler(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.jpeg;*.tif;*.bmp', 'Image Files (*.jpg,*.png,*.jpeg,*.tif,*.bmp)'}, 'Select an image file');
            
            if isequal(file, 0) || isequal(path, 0)
               % Handle the case when the user cancels the file selection
               app.NoFileSelectedLabel.Text = 'No file selected';
               app.NoFileSelectedLabel_2.Text = 'No file selected';
               return; % Exit the function
            end
            

            % Create the full file path
            fullFilePath = fullfile(path, file);
            % [ ~ , ~ , ext ] = fileparts( fullFilePath );
  
            % Display the image in the app.Image component
            % Load the BMP image
            bmpImage = imread(fullFilePath);
        
            app.Fullimagename = fullFilePath;
            app.Inputimagedata = bmpImage; 

            % Convert BMP to PNG format
            % You may need to adjust the filename and path as needed
            pngFileName = 'converted_image.png';
            pngFilePath = fullfile(path, pngFileName);
            imwrite(bmpImage, pngFilePath);
            
          
            numChannels = size(bmpImage, 3);

            % set all variable
            app.setText(file)
            app.setHistogramInput(numChannels);
        end

        % Button pushed function: EqualizationProcess
        function processEqualization(app, event)
            [N, M] = size(app.Inputimagedata);
            % Mengubah citra Image yang berukuran N x M dengan melakukan perataan
            % histogram (histogram equalization).
            
            % Hitung histogram citra
            HistInput = zeros(256, 1);
            HistOutput = zeros(256, 1);

            app.Outputimagedata = app.Inputimagedata;
            for i = 1:N
                for j = 1:M
                    intensity = app.Inputimagedata(i, j) + 1; % +1 untuk mengatasi nilai intensitas dari 0 hingga 255
                    HistInput(intensity) = HistInput(intensity) + 1;
                end
            end
        
            % Hitung histogram citra
            sum = 0.0;
            for i = 1:256
                sum = sum + HistInput(i);
                HistOutput(i) = floor(255 * sum / (N * M));
            end
        
            % Update citra sesuai histogram hasil perataan
            for i = 1:N
                for j = 1:M
                    app.Outputimagedata(i, j) = HistOutput(app.Inputimagedata(i, j) + 1);
                end
            end

            numChannels = size(app.Outputimagedata, 3);
        
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: LogProcess
        function processLog(app, event)
            c = app.LogC.Value;
            app.Outputimagedata = c * log(1 + im2double(app.Inputimagedata));            

            numChannels = size(app.Outputimagedata, 3);
            setHistogramOutput(app, numChannels);
        end

        % Button pushed function: PowerProcess
        function processPower(app, event)
            c = app.PowerC.Value;
            gamma = app.PowerGamma.Value;
            app.Outputimagedata = c * (im2double(app.Inputimagedata) .^ gamma);

            numChannels = size(app.Outputimagedata, 3);
            setHistogramOutput(app, numChannels);
        end

        % Button pushed function: StretchingProcess
        function processStretching(app, event)
            numChannels = size(app.Inputimagedata, 3);
            if numChannels == 1
                rmin = min(min(app.Inputimagedata));
                rmax = max(max(app.Inputimagedata));
                app.Outputimagedata = (app.Inputimagedata - rmin) .* (255/ (rmax - rmin));
            elseif numChannels == 3
                app.Outputimagedata = app.Inputimagedata;
                rmin = [0, 0, 0];
                rmax = [0, 0, 0];
                for i = 1:numChannels
                    rmin(i) = min(min(app.Inputimagedata(:,:,i)));
                    rmax(i) = max(max(app.Inputimagedata(:,:,i)));
                end
                rmin = max(rmin);
                rmax = min(rmax);
                app.Outputimagedata = (app.Inputimagedata - rmin) .* (255/ (rmax - rmin));
            else
                msgbox('Unsupported image format', 'Error', 'error');
            end

            setHistogramOutput(app, numChannels)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @chooseFileHandler, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open...';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.HistogramMenu = uimenu(app.ToolsMenu);
            app.HistogramMenu.Text = 'Histogram';
            app.BrigthnessMenu = uimenu(app.ToolsMenu);
            app.BrigthnessMenu.Text = 'Brigthness';
            app.NegativeMenu = uimenu(app.ToolsMenu);
            app.NegativeMenu.Text = 'Negative';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutFototokoMenu = uimenu(app.HelpMenu);
            app.AboutFototokoMenu.Text = 'About Fototoko..';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.Text = 'Help...';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'icon_chart.svg');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'icon_sun.svg');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Icon = fullfile(pathToMLAPP, 'icon_minus.svg');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.GridLayout6 = uigridlayout(app.HistogramTab);
            app.NoFileSelectedLabel = uilabel(app.GridLayout6);
            app.NoFileSelectedLabel.Text = 'No File Selected';
            app.ChooseFIleButton = uibutton(app.GridLayout6, 'push');
            app.ChooseFIleButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFileHandler, true);
            app.ChooseFIleButton.Text = 'Choose FIle';
            app.BrightnessTab = uitab(app.TabGroup);
            app.BrightnessTab.Title = 'Brightness';
            app.GridLayout2 = uigridlayout(app.BrightnessTab);
            app.aEditFieldLabel = uilabel(app.GridLayout2);
            app.aEditFieldLabel.Text = 'a';
            app.BrightnessA = uieditfield(app.GridLayout2, 'numeric');
            app.BrightnessA.Value = 1;
            app.ProcessButton = uibutton(app.GridLayout2, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @processBrightness, true);
            app.ProcessButton.Text = 'Process';
            app.bEditFieldLabel = uilabel(app.GridLayout2);
            app.bEditFieldLabel.Text = 'b';
            app.BrightnessB = uieditfield(app.GridLayout2, 'numeric');
            app.EnteradanbvaluesarbLabel = uilabel(app.GridLayout2);
            app.EnteradanbvaluesarbLabel.Text = 'Enter a dan b value (s = ar + b)';
            app.ApplyButton_2 = uibutton(app.GridLayout2, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton_2.Text = 'Apply';
            app.NegativeTab = uitab(app.TabGroup);
            app.NegativeTab.Title = 'Negative';
            app.GridLayout3 = uigridlayout(app.NegativeTab);
            app.ProcessButton_2 = uibutton(app.GridLayout3, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @processNegative, true);
            app.ProcessButton_2.Text = 'Process';
            app.ApplyButton = uibutton(app.GridLayout3, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton.Text = 'Apply';
            app.LogTransformationTab = uitab(app.TabGroup);
            app.LogTransformationTab.Title = 'Log Transformation';
            app.GridLayout2_2 = uigridlayout(app.LogTransformationTab);
            app.cLabel = uilabel(app.GridLayout2_2);
            app.cLabel.Text = 'c';
            app.LogC = uieditfield(app.GridLayout2_2, 'numeric');
            app.LogC.Value = 1;
            app.LogProcess = uibutton(app.GridLayout2_2, 'push');
            app.LogProcess.ButtonPushedFcn = createCallbackFcn(app, @processLog, true);
            app.LogProcess.Text = 'Process';
            app.Entercvaluesclog1rLabel = uilabel(app.GridLayout2_2);
            app.Entercvaluesclog1rLabel.Text = 'Enter c value (s = c log(1 + r))';
            app.LogApply = uibutton(app.GridLayout2_2, 'push');
            app.LogApply.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.LogApply.Text = 'Apply';
            app.PowerTransformationTab = uitab(app.TabGroup);
            app.PowerTransformationTab.Title = 'Power Transformation';
            app.GridLayout2_3 = uigridlayout(app.PowerTransformationTab);
            app.cLabel_2 = uilabel(app.GridLayout2_3);
            app.cLabel_2.Text = 'c';
            app.PowerC = uieditfield(app.GridLayout2_3, 'numeric');
            app.PowerC.Value = 1;
            app.PowerProcess = uibutton(app.GridLayout2_3, 'push');
            app.PowerProcess.ButtonPushedFcn = createCallbackFcn(app, @processPower, true);
            app.PowerProcess.Text = 'Process';
            app.EntercandgammavaluescrgammaLabel = uilabel(app.GridLayout2_3);
            app.EntercandgammavaluescrgammaLabel.Text = 'Enter c and gamma value (s = c r^gamma)';
            app.LogApply_2 = uibutton(app.GridLayout2_3, 'push');
            app.LogApply_2.Text = 'Apply';
            app.cLabel_3 = uilabel(app.GridLayout2_3);
            app.cLabel_3.Text = 'gamma';
            app.PowerGamma = uieditfield(app.GridLayout2_3, 'numeric');
            app.PowerGamma.Value = 1;
            app.ContrastStretchingTab = uitab(app.TabGroup);
            app.ContrastStretchingTab.Title = 'Contrast Stretching';
            app.GridLayout2_4 = uigridlayout(app.ContrastStretchingTab);
            app.StretchingProcess = uibutton(app.GridLayout2_4, 'push');
            app.StretchingProcess.ButtonPushedFcn = createCallbackFcn(app, @processStretching, true);
            app.StretchingProcess.Text = 'Process';
            app.LogApply_3 = uibutton(app.GridLayout2_4, 'push');
            app.LogApply_3.Text = 'Apply';
            app.EqualizationTab = uitab(app.TabGroup);
            app.EqualizationTab.Title = 'Equalization';
            app.GridLayout7 = uigridlayout(app.EqualizationTab);
            app.ChooseFIleButton_2 = uibutton(app.GridLayout7, 'push');
            app.ChooseFIleButton_2.ButtonPushedFcn = createCallbackFcn(app, @chooseFileHandler, true);
            app.ChooseFIleButton_2.Text = 'Choose FIle';
            app.NoFileSelectedLabel_2 = uilabel(app.GridLayout7);
            app.NoFileSelectedLabel_2.Text = 'No File Selected';
            app.EqualizationProcess = uibutton(app.GridLayout7, 'push');
            app.EqualizationProcess.ButtonPushedFcn = createCallbackFcn(app, @processEqualization, true);
            app.EqualizationProcess.Text = 'Process';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.HistogramTab_2 = uitab(app.TabGroup2);
            app.HistogramTab_2.Title = 'Histogram';
            app.GridLayout4 = uigridlayout(app.HistogramTab_2);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout4);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout4);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout4);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout4);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.InputLabel = uilabel(app.GridLayout4);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.GridLayout4);
            app.OutputLabel.Text = 'Output';
            app.OutputImageTab = uitab(app.TabGroup2);
            app.OutputImageTab.Title = 'Output Image';
            app.GridLayout5 = uigridlayout(app.OutputImageTab);
            app.OutputImage = uiimage(app.GridLayout5);
            app.InputImage = uiimage(app.GridLayout5);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adohe_fototoko

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end